classdef appecg1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        DiagnosisTextArea        matlab.ui.control.TextArea
        DiagnosisTextAreaLabel   matlab.ui.control.Label
        DurationsEditField       matlab.ui.control.NumericEditField
        DurationsEditFieldLabel  matlab.ui.control.Label
        ChannelDropDown          matlab.ui.control.DropDown
        ChannelDropDownLabel     matlab.ui.control.Label
        ProcessECGButton         matlab.ui.control.Button
        UIAxes2                  matlab.ui.control.UIAxes
        UIAxes                   matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ProcessECGButton
        function ProcessECGButtonPushed2(app, event)
    % Load ECG signal
    [signal, Fs, tm] = rdsamp('x0010');

    % Read selected channel and duration
    channel = app.ChannelDropDown.Value;
    duration = app.DurationsEditField.Value;

    % Process ECG
    results = processECG(signal, Fs, tm, channel, duration);

    % Create report
    report = {};
    report{end+1} = sprintf('Mean HR: %.1f BPM', results.mean_heart_rate);
    report{end+1} = sprintf('SDNN: %.1f ms | RMSSD: %.1f ms', ...
                            results.sdnn, results.rmssd);
    report{end+1} = sprintf('Mean ST shift: %.3f mV', ...
                            results.mean_st_shift);
    report{end+1} = '-----------------------------------';

    if results.mean_heart_rate > 100
        report{end+1} = 'ALERT: Tachycardia (>100 BPM)';
    elseif results.mean_heart_rate < 60
        report{end+1} = 'ALERT: Bradycardia (<60 BPM)';
    else
        report{end+1} = 'Heart rate: Normal range';
    end

    if results.sdnn > 100
        report{end+1} = 'ALERT: Irregular rhythm indicator';
    else
        report{end+1} = 'RR regularity: Stable';
    end

    if results.mean_st_shift > 0.10
        report{end+1} = 'EDUCATIONAL INDICATOR: ST elevation';
    elseif results.mean_st_shift < -0.10
        report{end+1} = 'EDUCATIONAL INDICATOR: ST depression';
    else
        report{end+1} = 'ST segment: Isoelectric';
    end

    % Display report
report{end+1} = ' ';
report{end+1} = 'Educational use only - not for clinical diagnosis.';

app.DiagnosisTextArea.Value = report;
    app.DiagnosisTextArea.Value = report;

    % PQRST delineation plot
    cla(app.UIAxes);

    plot(app.UIAxes, results.time, results.ecg_filtered, ...
         'Color', [0.2 0.2 0.2]);

    hold(app.UIAxes, 'on');

    plot(app.UIAxes, results.time(results.locations), ...
         results.ecg_filtered(results.locations), ...
         'ro', 'MarkerFaceColor', 'r');

    plot(app.UIAxes, results.time(results.p_locs), ...
         results.ecg_filtered(results.p_locs), ...
         'bs', 'MarkerFaceColor', 'b');

    plot(app.UIAxes, results.time(results.q_locs), ...
         results.ecg_filtered(results.q_locs), ...
         'mv', 'MarkerFaceColor', 'm');

    plot(app.UIAxes, results.time(results.s_locs), ...
         results.ecg_filtered(results.s_locs), ...
         'k^', 'MarkerFaceColor', 'k');

    plot(app.UIAxes, results.time(results.t_locs), ...
         results.ecg_filtered(results.t_locs), ...
         'gd', 'MarkerFaceColor', 'g');

    hold(app.UIAxes, 'off');
    grid(app.UIAxes, 'on');

    title(app.UIAxes, sprintf('PQRST Delineation - Channel %d', channel));
    xlabel(app.UIAxes, 'Time (s)');
    ylabel(app.UIAxes, 'Amplitude (mV)');
    xlim(app.UIAxes, [0 min(10, duration)]);

    % Instantaneous heart-rate plot
    cla(app.UIAxes2);

    plot(app.UIAxes2, results.heart_rate, 'o-', ...
         'Color', [0.85 0.25 0.2]);

    grid(app.UIAxes2, 'on');
    yline(app.UIAxes2, 60, '--k');
    yline(app.UIAxes2, 100, '--r');

    title(app.UIAxes2, 'Instantaneous Heart Rate');
    xlabel(app.UIAxes2, 'Beat Number');
    ylabel(app.UIAxes2, 'BPM');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.ProcessECGButton = uibutton(app.UIFigure, 'push');
            app.ProcessECGButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessECGButtonPushed2, true);
            app.ProcessECGButton.Text = 'Process ECG';
            app.ChannelDropDownLabel = uilabel(app.UIFigure);
            app.ChannelDropDownLabel.Text = 'Channel';
            app.ChannelDropDown = uidropdown(app.UIFigure);
            app.ChannelDropDown.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12'};
            app.ChannelDropDown.ItemsData = [1 2 3 4 5 6 7 8 9 10 11 12];
            app.ChannelDropDown.Value = 1;
            app.DurationsEditFieldLabel = uilabel(app.UIFigure);
            app.DurationsEditFieldLabel.Text = 'Duration (s)';
            app.DurationsEditField = uieditfield(app.UIFigure, 'numeric');
            app.DurationsEditField.Value = 60;
            app.DiagnosisTextAreaLabel = uilabel(app.UIFigure);
            app.DiagnosisTextAreaLabel.Text = 'Diagnosis';
            app.DiagnosisTextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appecg1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end